classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeomaterialMorphologicalCharacterizationSoftwareUIFigure  matlab.ui.Figure
        ascDataFilesButtonGroup         matlab.ui.container.ButtonGroup
        ChangeButton                    matlab.ui.control.Button
        LocationEditField               matlab.ui.control.EditField
        LocationEditFieldLabel          matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        CalculationButtonGroup          matlab.ui.container.ButtonGroup
        RunButton                       matlab.ui.control.Button
        PrintingCheckBox_2              matlab.ui.control.CheckBox
        ResultfolderButton              matlab.ui.control.Button
        SpatialProjectionCheckBox       matlab.ui.control.CheckBox
        BoundBoxCheckBox                matlab.ui.control.CheckBox
        AlphaShapeCheckBox              matlab.ui.control.CheckBox
        PlottingunchecktospeedupCheckBox  matlab.ui.control.CheckBox
        SpatialProjectionVectorsButtonGroup  matlab.ui.container.ButtonGroup
        IntervalsofphiEditField         matlab.ui.control.NumericEditField
        IntervalsofphiEditFieldLabel    matlab.ui.control.Label
        IntervalsofthetaEditField       matlab.ui.control.NumericEditField
        IntervalsofthetaEditFieldLabel  matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        PrintingCheckBox                matlab.ui.control.CheckBox
        LogsTextArea                    matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Input
        file_dir = '';
        task_name = '';

        % Projection vectors
        itv_theta = '';
        itv_phi = '';
        proj_vec = '';

        % Output
        result = {};
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % File location
            currentPath = pwd;  % Current directory
            app.LocationEditField.Value = currentPath;
            app.file_dir = app.LocationEditField.Value;
            
            % Generate projection vectors
            app.itv_theta = app.IntervalsofthetaEditField.Value;
            app.itv_phi = app.IntervalsofphiEditField.Value;
            toggle_print = app.PrintingCheckBox.Value;
            
            app.proj_vec = gen_proj_vec(app, app.file_dir, app.itv_theta, app.itv_phi, toggle_print);
            
            % Message
            app.LogsTextArea.Value = sprintf('INITIALIZED.');
            
            % Panel
            app.LogsTextArea.Editable = false;
            app.AlphaShapeCheckBox.Enable = 'off';
            app.ResultfolderButton.Enable = 'off';
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % Message
            app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('SEARCHING files.......')];
            app.file_dir = app.LocationEditField.Value;

            % Search files
            [app.file_dir, app.task_name] = search_inp_file(app.file_dir, '*.asc');
            
            % Message
            str1 = '';
            for i = 1:length(app.task_name)
                str1 = [str1, app.task_name{i}, '.asc, '];
            end
            str2 = sprintf('Finished, %d files are found.', length(app.task_name));
            app.LogsTextArea.Value = [app.LogsTextArea.Value; str1; str2];
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            % Prompt the user with a directory selection dialog
            currentPath = pwd;
            selectedDir = uigetdir(currentPath, 'Select a directory');
            
            % Check if the user canceled or selected a directory
            if ischar(selectedDir)
                app.LocationEditField.Value = selectedDir;
                app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('File directory is changed.')];
            else
                app.LocationEditField.Value = currentPath;
                app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('File directory is NOT changed.')];
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Message
            app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('GENERATING projection vectors.......')];
            
            % Generate projection vectors
            app.itv_theta = app.IntervalsofthetaEditField.Value;
            app.itv_phi = app.IntervalsofphiEditField.Value;
            toggle_print = app.PrintingCheckBox.Value;

            app.proj_vec = gen_proj_vec(app, app.file_dir, app.itv_theta, app.itv_phi, toggle_print);
        
            % Message
            str = sprintf('Finished, %d projection vectors are generated.', numel(app.proj_vec));
            app.LogsTextArea.Value = [app.LogsTextArea.Value; str];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Panel: disable buttons
            app.ChangeButton.Enable = 'off';
            app.SearchButton.Enable = 'off';
            app.GenerateButton.Enable = 'off';
            app.RunButton.Enable = 'off';

            toggle_visual = app.PlottingunchecktospeedupCheckBox.Value;
            toggle_print = app.PrintingCheckBox_2.Value;
            toggle_bound_box = app.BoundBoxCheckBox.Value;
            toggle_spatial_proj = app.SpatialProjectionCheckBox.Value;

            app.result = cell(length(app.task_name), 4 * (numel(app.proj_vec)) + 9);
            
            % Message
            app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('CALCULATING.......')];
            
            for i = 1:length(app.task_name)
                name = app.task_name{i, 1};
                app.result{i, 1} = name;

                app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('Consumed time of %s.asc: ', name)];
                drawnow;
                
                tic;
                % Generate alpha shape
                [p_cen, alpha_radius, shape_vol, shape_surf_area] = gen_alpha_shape(app, app.file_dir, name, toggle_visual);
                app.result{i, 2} = shape_vol;
                app.result{i, 3} = shape_surf_area;

                elapsedTime = toc;
                app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('Alpha shape \t %.2f s', elapsedTime)];
                drawnow;
                
                if toggle_bound_box
                    tic;
                    % Generate bounding box
                    [min_bd_1, min_bd_2, min_bd_3, max_bd_1, max_bd_2, max_bd_3] = gen_bound_box(name, app.file_dir, p_cen, toggle_visual);
                    app.result{i, 4} = min_bd_3; % length, length >= width >= thickness
                    app.result{i, 5} = min_bd_2; % width
                    app.result{i, 6} = min_bd_1; % thickness
                    app.result{i, 7} = max_bd_3; % length, length >= width >= thickness
                    app.result{i, 8} = max_bd_2; % width
                    app.result{i, 9} = max_bd_1; % thickness
    
                    elapsedTime = toc;
                    app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('Bound box \t %.2f s', elapsedTime)];
                    drawnow;
                end

                if toggle_spatial_proj
                    % calculate the random bounding boxes of point clouds
                    proj_result = gen_proj_geo(app, name, app.file_dir, app.proj_vec, p_cen, alpha_radius, app.itv_theta, app.itv_phi, toggle_visual);
                    for j = 1: 4 * numel(app.proj_vec)
                        app.result{i, j + 9} = proj_result{1, j};
                    end
                end
            end

            % Message
            newStr = sprintf('Finished, %d files are analyzed.', length(app.task_name));
            app.LogsTextArea.Value = [app.LogsTextArea.Value; newStr];
            drawnow;
            
            if toggle_print
                % Message
                app.LogsTextArea.Value = [app.LogsTextArea.Value; sprintf('OUTPUT.......')];
                drawnow;
                
                gen_result_file(app.file_dir, app.task_name, app.proj_vec, app.result, toggle_print);
                
                % Message
                newStr = sprintf('Finished, results are output.');
                app.LogsTextArea.Value = [app.LogsTextArea.Value; newStr];
                drawnow;
            end

            % Panel: re-enable all buttons
            app.ChangeButton.Enable = 'on';
            app.SearchButton.Enable = 'on';
            app.GenerateButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            app.ResultfolderButton.Enable = 'on';
        end

        % Button pushed function: ResultfolderButton
        function ResultfolderButtonPushed(app, event)
            system(['explorer "', app.file_dir, '"']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure = uifigure('Visible', 'off');
            app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure.Name = 'Geomaterial Morphological Characterization Software';
            app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure.Icon = fullfile(pathToMLAPP, 'img', 'logo.png');
            app.UIAxes = uiaxes(app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure);
            app.LogsTextArea = uitextarea(app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure);
            app.SpatialProjectionVectorsButtonGroup = uibuttongroup(app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure);
            app.SpatialProjectionVectorsButtonGroup.Title = 'Spatial Projection Vectors';
            app.PrintingCheckBox = uicheckbox(app.SpatialProjectionVectorsButtonGroup);
            app.PrintingCheckBox.Text = 'Printing';
            app.PrintingCheckBox.Value = true;
            app.GenerateButton = uibutton(app.SpatialProjectionVectorsButtonGroup, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.IntervalsofthetaEditFieldLabel = uilabel(app.SpatialProjectionVectorsButtonGroup);
            app.IntervalsofthetaEditFieldLabel.Text = 'Intervals of theta';
            app.IntervalsofthetaEditField = uieditfield(app.SpatialProjectionVectorsButtonGroup, 'numeric');
            app.IntervalsofthetaEditField.Limits = [2 Inf];
            app.IntervalsofthetaEditField.ValueDisplayFormat = '%.0f';
            app.IntervalsofthetaEditField.Value = 4;
            app.IntervalsofphiEditFieldLabel = uilabel(app.SpatialProjectionVectorsButtonGroup);
            app.IntervalsofphiEditFieldLabel.Text = 'Intervals of phi';
            app.IntervalsofphiEditField = uieditfield(app.SpatialProjectionVectorsButtonGroup, 'numeric');
            app.IntervalsofphiEditField.Limits = [2 Inf];
            app.IntervalsofphiEditField.ValueDisplayFormat = '%.0f';
            app.IntervalsofphiEditField.Value = 4;
            app.CalculationButtonGroup = uibuttongroup(app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure);
            app.CalculationButtonGroup.Title = 'Calculation';
            app.PlottingunchecktospeedupCheckBox = uicheckbox(app.CalculationButtonGroup);
            app.PlottingunchecktospeedupCheckBox.Text = 'Plotting (uncheck to speed up)';
            app.PlottingunchecktospeedupCheckBox.Value = true;
            app.AlphaShapeCheckBox = uicheckbox(app.CalculationButtonGroup);
            app.AlphaShapeCheckBox.Text = 'Alpha Shape';
            app.AlphaShapeCheckBox.Value = true;
            app.BoundBoxCheckBox = uicheckbox(app.CalculationButtonGroup);
            app.BoundBoxCheckBox.Text = 'Bound Box';
            app.BoundBoxCheckBox.Value = true;
            app.SpatialProjectionCheckBox = uicheckbox(app.CalculationButtonGroup);
            app.SpatialProjectionCheckBox.Text = 'Spatial Projection';
            app.SpatialProjectionCheckBox.Value = true;
            app.ResultfolderButton = uibutton(app.CalculationButtonGroup, 'push');
            app.ResultfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ResultfolderButtonPushed, true);
            app.ResultfolderButton.Text = 'Result folder';
            app.PrintingCheckBox_2 = uicheckbox(app.CalculationButtonGroup);
            app.PrintingCheckBox_2.Text = 'Printing';
            app.PrintingCheckBox_2.Value = true;
            app.RunButton = uibutton(app.CalculationButtonGroup, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ascDataFilesButtonGroup = uibuttongroup(app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure);
            app.ascDataFilesButtonGroup.Title = '*.asc Data Files';
            app.SearchButton = uibutton(app.ascDataFilesButtonGroup, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.LocationEditFieldLabel = uilabel(app.ascDataFilesButtonGroup);
            app.LocationEditFieldLabel.Text = 'Location:';
            app.LocationEditField = uieditfield(app.ascDataFilesButtonGroup, 'text');
            app.ChangeButton = uibutton(app.ascDataFilesButtonGroup, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            % Show the figure after all components are created
            app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeomaterialMorphologicalCharacterizationSoftwareUIFigure)
        end
    end
end